classdef SignalRecordInator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                  matlab.ui.Figure
        MainGrid                    matlab.ui.container.GridLayout
        ResetButton                 matlab.ui.control.Button
        PlotPanel                   matlab.ui.container.Panel
        RecordButton                matlab.ui.control.StateButton
        OptionTabs                  matlab.ui.container.TabGroup
        RecordingTab                matlab.ui.container.Tab
        RecordingGrid               matlab.ui.container.GridLayout
        NumSamplesEditField         matlab.ui.control.NumericEditField
        NumSamplesEditFieldLabel    matlab.ui.control.Label
        RecordingModeDropDown       matlab.ui.control.DropDown
        RecordingModeDropDownLabel  matlab.ui.control.Label
        InputsPanel                 matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        AI3CheckBox                 matlab.ui.control.CheckBox
        AI2CheckBox                 matlab.ui.control.CheckBox
        AI1CheckBox                 matlab.ui.control.CheckBox
        AI0CheckBox                 matlab.ui.control.CheckBox
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        VisualizingTab              matlab.ui.container.Tab
        VisGrid                     matlab.ui.container.GridLayout
        YmodeDropDown               matlab.ui.control.DropDown
        YmodeDropDownLabel          matlab.ui.control.Label
        YminEditField               matlab.ui.control.NumericEditField
        YminEditFieldLabel          matlab.ui.control.Label
        YmaxEditField               matlab.ui.control.NumericEditField
        YmaxEditFieldLabel          matlab.ui.control.Label
        LegendCheckBox              matlab.ui.control.CheckBox
        ChartHistoryEditField       matlab.ui.control.NumericEditField
        ChartHistoryEditFieldLabel  matlab.ui.control.Label
        PlotModeDropDown            matlab.ui.control.DropDown
        PlotModeDropDownLabel       matlab.ui.control.Label
        LineDefsEditField           matlab.ui.control.EditField
        LineDefsEditFieldLabel      matlab.ui.control.Label
        SavingTab                   matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        NoteEditField               matlab.ui.control.EditField
        NoteEditFieldLabel          matlab.ui.control.Label
        DataSavingMessage           matlab.ui.control.Label
        IncludeTimeColumnCheckBox   matlab.ui.control.CheckBox
        SaveDataCheckBox            matlab.ui.control.CheckBox
        SetSaveFileButton           matlab.ui.control.Button
        FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        recordingSession % this stores the connection to the DAQ and handles communications
        saveFileID % for writting data to a file, this is the file ID
        startTime %for recording the date stamp. Not currently used
        baseDir %for making the UI of saving files a little more intelligent so it starts in whatever dir was last accessed
        inputOptions = {'AI0','AI1','AI2','AI3'}; %presently these are the only analog inputs we expose
        
        plotLayout %This is the main layout for the tiled plots for visualizing the signals
        axes={} %container to hold all of the plot axes
        dataLines={} %container to hold all of the animated lines of live updating data
        defaultLines = {"k-","r-","b-","g-"}; %default line specs
        samplesRecorded = 0; %How many samples we have read in the current session

    end
    
    methods (Access = private)
        function toggleChildren(app,container,value)
            %This helper function is used to en-mass turn on or off UI
            %elements. Mostly used to toggle off settings while recording
            %so we cannot break the app mid record.

            %check if the current thing can be enabled
            if isprop(container,"Enable")
                container.Enable = value;%toggle value
            end
            %If we passed a cell array, re-call on each child
            if iscell(container)
                for subcellIx = 1:length(container)
                    app.toggleChildren(container{subcellIx},value)
                end
                return
            end
            %recursively re-call on all children of each object
            if isprop(container,"Children")
                for chIx = 1:length(container.Children)
                    child = container.Children(chIx);
                    app.toggleChildren(child,value);
                end
            end

        end

        function tf = isValidLineSpec(app,spec)
            %isValidLineSpec Determine whether a string is a valid MATLAB LineSpec.
            %
            %   tf = isValidLineSpec(spec)
            %
            %   Returns true if SPEC is a valid MATLAB LineSpec and false otherwise.
            %
            %   Supported LineSpec components:
            %       Colors:     r g b c m y k w
            %       LineStyles: - -- : -.
            %       Markers:    + o * . x s d ^ v > < p h _ |
            %
            %   "none" is also accepted as a line style.
            %
            %   Examples:
            %       isValidLineSpec("r-")       % true
            %       isValidLineSpec("--bo")     % true
            %       isValidLineSpec("o:r")      % true
            %       isValidLineSpec("none")     % true
            %       isValidLineSpec("red-")     % false
            %       isValidLineSpec("rr-")      % false
            %       isValidLineSpec("--o--")    % false
            %
            %   Note:
            %       This validates the LineSpec shorthand syntax, not arbitrary
            %       Name-Value graphics properties such as Color=[1 0 0].
            %       This function is pretty execssive but I wanted to make
            %       it so a user could define their own lines, and I wanted
            %       a way to make sure those were valid line specs so we
            %       need thes and a couple other helper functions
            
                % Must be a scalar string or character vector
                if ~(ischar(spec) || (isstring(spec) && isscalar(spec)))
                    tf = false;
                    return
                end
                %ensure we are working with a character type
                spec = char(spec);
            
                % Empty LineSpec is valid: MATLAB interprets it as default properties.
                if isempty(spec)
                    tf = true;
                    return
                end
            
                % "none" is a special complete LineSpec.
                if strcmp(spec, 'none')
                    tf = true;
                    return
                end
            
                % LineSpec components
                colors  = 'rgbcmykw';
                markers = '+o*.xsd^v><ph_|';
                styles  = {'-', '--', ':', '-.'};
            
                % Keep track of which component types have already been found.
                hasColor  = false;
                hasMarker = false;
                hasStyle  = false;
            
                currentChar = 1;
                totalSpecLength = length(spec);
            
                while currentChar <= totalSpecLength
            
                    % -------------------------------------------------------------
                    % Check for a line style.
                    %
                    % The two-character styles must be checked before '-'.
                    % -------------------------------------------------------------
                    if currentChar < totalSpecLength
                        twoChar = spec(currentChar:currentChar+1);
            
                        if strcmp(twoChar, '--') || strcmp(twoChar, '-.')
                            if hasStyle
                                tf = false;
                                return
                            end
            
                            hasStyle = true;
                            currentChar = currentChar + 2;
                            continue
                        end
                    end
            
                    % Single-character line styles
                    if any(spec(currentChar) == ['-' ':'])
                        if hasStyle
                            tf = false;
                            return
                        end
            
                        hasStyle = true;
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % -------------------------------------------------------------
                    % Color
                    % -------------------------------------------------------------
                    if any(spec(currentChar) == colors)
                        if hasColor
                            tf = false;
                            return
                        end
            
                        hasColor = true;
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % -------------------------------------------------------------
                    % Marker
                    % -------------------------------------------------------------
                    if any(spec(currentChar) == markers)
                        if hasMarker
                            tf = false;
                            return
                        end
            
                        hasMarker = true;
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % Unknown character
                    tf = false;
                    return
                end
            
                tf = true;
            end

        function nv = parseLineSpec(app, spec)
            %parseLineSpec Parse a MATLAB LineSpec into name-value pairs.
            %
            %   nv = parseLineSpec(spec)
            %
            %   Returns a cell array suitable for passing directly to animatedline:
            %
            %       nv = parseLineSpec("r--o");
            %       al = animatedline(ax, nv{:});
            %
            %   Supported components:
            %       Colors:     r g b c m y k w
            %       LineStyles: - -- : -.
            %       Markers:    + o * . x s d ^ v > < p h _ |
            %       Special:    none
            %   Note: Again a bit ecessive in the complicated department
            %   but for the same reason of allowing user defined lines for
            %   each plot
            
            
                spec = char(spec);
            
                if ~app.isValidLineSpec(spec)
                    error('parseLineSpec:InvalidLineSpec', ...
                        '"%s" is not a valid MATLAB LineSpec.', spec);
                end
            
                nv = {};
            
                if isempty(spec)
                    return
                end
            
                % "none" means LineStyle = none
                if strcmp(spec, 'none')
                    nv = {'LineStyle', 'none'};
                    return
                end
            
                colors  = 'rgbcmykw';
                markers = '+o*.xsd^v><ph_|';
            
                currentChar = 1;
                specLength = length(spec);
            
                while currentChar <= specLength
            
                    % Two-character line styles
                    if currentChar < specLength
                        twoChar = spec(currentChar:currentChar+1);
            
                        if strcmp(twoChar, '--') || strcmp(twoChar, '-.')
                            nv(end+1:end+2) = {'LineStyle', twoChar};
                            currentChar = currentChar + 2;
                            continue
                        end
                    end
            
                    % Single-character line styles
                    if any(spec(currentChar) == ['-' ':'])
                        nv(end+1:end+2) = {'LineStyle', spec(currentChar)};
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % Color
                    if any(spec(currentChar) == colors)
                        nv(end+1:end+2) = {'Color', spec(currentChar)};
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % Marker
                    if any(spec(currentChar) == markers)
                        nv(end+1:end+2) = {'Marker', spec(currentChar)};
                        currentChar = currentChar + 1;
                        continue
                    end
            
                    % Should be unreachable after validation
                    error('parseLineSpec:InternalError', ...
                        'Unable to parse LineSpec "%s".', spec);
                end
            end
            
        function [channelStates] = checkChannelRecordingStates(app)
            %Helper function to check which inputs have been selected for
            %recording
            channelStates = [app.AI0CheckBox.Value,...
                             app.AI1CheckBox.Value,...
                             app.AI2CheckBox.Value,...
                             app.AI3CheckBox.Value,];
            
        end

        function [colors] = grabColors(app)
            %helper function to grab the colors from the line definition
            %edit field, validate them, and if they are not valud then use
            %the defaults

            %grab what we have specified
            colorStr = app.LineDefsEditField.Value;
            
            %seperate them out
            colors = split(colorStr,',');
            if length(colors)~= 4 %if we do not have 4, we fall back to defaults
                colors = app.defaultLines;
            end
            %for each one, if it is valid use it, otherwise replace with a
            %default
            for cIx = 1:length(colors)
                if ~app.isValidLineSpec(colors{cIx})
                    colors{cIx} = app.defaultLines{cIx};
                end
            end
            %finally, set the edit field to match what we are using
            %(switching out the defaults as needed)
            app.LineDefsEditField.Value = sprintf("%s,%s,%s,%s",colors{1},colors{2},colors{3},colors{4});

            
        end

        function clearPlots(app)
            %Resets the plot view and resets internal components related to
            %plotting.

            %First set up the default empty containers
            app.axes={};
            app.dataLines = {};
            %Clear the existing plot by putting a new one over it
            app.plotLayout = tiledlayout(app.PlotPanel,1,1);
            app.plotLayout.Padding = 'tight';
            app.plotLayout.TileSpacing = 'tight';
            %check which signals we are going to care about
            channelStates = app.checkChannelRecordingStates();

            if ~any(channelStates)
                %if we do not care about anything, then we are done
                return
            end

            numActiveChannels = sum(channelStates);
            channelNames = app.inputOptions(channelStates);
            %Now we need to either prepare a single plot for all signals or
            %an individual plot for each signal
            switch app.PlotModeDropDown.Value
                case "Single"
                    %Make one big plot in the plot panel
                    ax = nexttile(app.plotLayout,1);
                    app.axes = {ax};
                    %assign each line to use this plot as their parent
                    lineParents = ones(1,numActiveChannels);
                    title(ax,"Signals")
                    
                case "Multiple"
                    %Make a tiled layout with one plot per signal
                    app.plotLayout = tiledlayout(app.PlotPanel,1,numActiveChannels);
                    app.plotLayout.Padding = 'tight';
                    app.plotLayout.TileSpacing = 'tight';
                    for ix = 1:numActiveChannels
                        %For each tile, make the empty plot axes and store
                        %it
                        ax = nexttile(app.plotLayout,ix);
                        app.axes = [app.axes,{ax}];
                        title(ax,channelNames{ix});
                        
                    end
                    %each line has a parent that is an individual plot
                    %store their indices here
                    lineParents = 1:numActiveChannels;

            end
            %Now we initialize all of the animated lines based on how many
            %samples we plant to take
            numPoints = app.ChartHistoryEditField.Value;
            colors=  app.grabColors();
            for lineIx = 1:numActiveChannels%Create one line per channel
                lineSpec = app.parseLineSpec(colors{lineIx});
                lineHandle = animatedline('Parent',app.axes{lineParents(lineIx)},'MaximumNumPoints', numPoints,'linewidth',1.5,lineSpec{:});
                app.dataLines = [app.dataLines,{lineHandle}];
                
            end
            %Now for each axes set up the labels and formatting
            for axIx = 1:length(app.axes)
                ax = app.axes{axIx};
                xlim(ax,"tight")
                ylabel(ax,"Voltage (V)")
                xlabel(ax,"Time (s)")
                
                %Set the y scale according to the User specifications
                switch app.YmodeDropDown.Value
                    case "Auto"
                        ylim(ax,"auto")
                    case "Fixed"
                        ylim(ax,[app.YminEditField.Value,app.YmaxEditField.Value]);

                end
                fontsize(ax,20,'points')
            end
            %Set up the legend if asked
            if app.LegendCheckBox.Value && strcmp(app.PlotModeDropDown.Value,'Single')
                legend(app.axes{1},app.inputOptions(channelStates),'FontSize',20)
            end
            %Update the plot in a framerate aware manner (do not over tax
            %the computer/limit the framerate to 30 fps)
            drawnow limitrate;

            
        end
        function saveData(app,t,data)
            %create a format string to save the data as floats with 6
            %decimal precision. One per data we plan to save
            formatString = repmat('%.6f,',1,size(data,2));
            if app.IncludeTimeColumnCheckBox.Value
                formatString = [formatString,'%6f,'];%add the time coulmn
                data = [t,data];
            end
            %clip off the final comma and add a newline
            formatString = [formatString(1:end-1),'\n'];
            %append the data to the save file
            fprintf(app.saveFileID,formatString, data');

        end

        function drawData(app,t,data)
            %Pack the new data into the animated lines
            channelStates = app.checkChannelRecordingStates();
            for channelIx = 1:sum(channelStates)
                lineHandle = app.dataLines{channelIx};
                addpoints(lineHandle,t,data(:,channelIx));
            end
            %Update the plot in a framerate aware manner (do not over tax
            %the computer/limit the framerate to 30 fps)
            drawnow limitrate;
        end
        
        function CatchRecordedData(app,src,event)
            %Each time the daq has data available, this function will call
            %It will check if we have enough data for fixed sample mode. It
            %will then send the data to be plotted and saved as needed

            %we don't recolor the record button until we finally have some
            %data to plot and deal with
            app.RecordButton.BackgroundColor = [0.1,1,0.1];

            stopRecording = false;
            %Catch the data from the daq
            [data, t] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            %Update the plots
            app.drawData(t, data);
            
            %Check if we have recorded the necessary number samples, if we
            %are in fixed samples mode
            if strcmp(app.RecordingModeDropDown.Value,"samples")
                newSamples = size(data,1);
                newSampleCount = app.samplesRecorded+newSamples;
                samplesToTarget =  app.NumSamplesEditField.Value - app.samplesRecorded;
                %If we have exceeded or met our target number of samples
                if samplesToTarget <= newSamples
                    stopRecording = true;%time to stop
                    %truncate to remove extra samples
                    data = data(1:samplesToTarget,:);
                    t = t(1:samplesToTarget);
                end
                app.samplesRecorded = newSampleCount;

            end
            %If we save data, pass it to the saving function
            if app.SaveDataCheckBox.Value == 1
                app.saveData(t,data);
            end

            if stopRecording%stop the recording if we need to
                app.RecordButton.Value = 0;
                app.RecordButtonValueChanged(struct());
            end


        end
        function panic(app,message)
            arguments
                app
                message = ""
            end
            uialert(app.MainWindow,sprintf("Something about the app is horked. Consider resetting. %s",message),"Recording error");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Run this on startup
            try
                %try to find and initilize the ni daq
                app.recordingSession = daq("ni");
                %set the callback function for when data is available
                app.recordingSession.ScansAvailableFcn = @app.CatchRecordedData;
            catch excpt
                % Get and display the full formatted error report
                errorMessage = getReport(excpt);
                fprintf(2, '%s\n', errorMessage);  
                %If we cannot find the nidaq alert the user
                app.recordingSession = [];
                uialert(app.MainWindow,"Setup error. Either no acquisition device was detected, or you are missing the Data Acquisition Toolbox.","Setup Error")
            end
            %capture base dir and reset plots
            app.baseDir = pwd();
            app.clearPlots();

        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            %This sets up a recording and starts the recording session
            %based on what settings the user has defined. Or it resets the
            %app when recording is done

            %reset the number of samples we have recorded
            app.samplesRecorded = 0;
            app.RecordButton.BackgroundColor = [0.96,0.96,0.96];
            value = app.RecordButton.Value;

            thingsToToggle = {'VisualizingTab',...
                              'RecordingTab',...
                              'SavingTab'
                              };
            %Either turn off or on the editable parameters depending on if
            %we are recording (value = 1) or not (value = 0)
            for thing = thingsToToggle
                app.toggleChildren(app.(thing{1}),~value);
            end
            switch value
                case 1 %If the value is 1, we are starting a new recording
                    try
                        %Clearly indicate that we are recording by changing the
                        %button color
                        app.RecordButton.BackgroundColor = [1,1,0.1];
    
                        app.clearPlots();%reset the plots
    
                        if isempty(app.recordingSession)
                            %if there is no recording session, we cannot
                            %record. Tell the user and stop early.
                            uialert(app.MainWindow,'Setup error. Either no acquisition device was detected, or you are missing the Data Acquisition Toolbox','Streaming Error')
                            app.RecordButton.Value = 0;%turn off recording
                            app.RecordButtonValueChanged(event);%reset
                            return
                        end
        
                        %Clean up all old channels
                        while ~isempty(app.recordingSession.Channels)
                            removechannel(app.recordingSession, 1);
                        end
    
                        %Add channels we checked to record
                        for thisInput = app.inputOptions
                            if app.(sprintf('%sCheckBox',thisInput{1})).Value == 1
                                fprintf('Recording from %s\n',thisInput{1});
                                addinput(app.recordingSession,'Dev1',lower(thisInput{1}),'Voltage');
                            end
                        end

                        %if we did not add anything, stop the recording
                        if isempty(app.recordingSession.Channels)
                            uialert(app.MainWindow,'Please select at least one channel to stream from','Streaming Error')
                            app.RecordButton.Value = 0;%turn off recording
                            app.RecordButtonValueChanged(event);%reset
                            return
                        end

                        %If we said we want to save
                        if app.SaveDataCheckBox.Value == 1
                            %First validate the file name(TODO: add more
                            %validation steps)
                            if strcmpi(app.FileNameEditField.Value,"") || ...
                                ~endsWith(app.FileNameEditField.Value,".txt")
                                app.FileNameEditField.Value = "";
                                uialert(app.MainWindow,"Invalid save file specified, not saving data.","Save error");
                                app.SaveDataCheckBox.Value= 0;
                            end
                        end
                        %if we still think we can save, set up the header
                        if app.SaveDataCheckBox.Value == 1
                            %Open the file in append mode to add more to it 
                            app.saveFileID = fopen(app.FileNameEditField.Value,"a");
                            %write out the headder
                            fprintf(app.saveFileID,'SignalRecordInator saved data\n');
                            %TODO: lint the input from the notes field
                            fprintf(app.saveFileID,'note:%s\n',app.NoteEditField.Value);
                            fprintf(app.saveFileID,'numChannels: %d\nChannels:',length(app.recordingSession.Channels));
                            if app.IncludeTimeColumnCheckBox.Value
                                fprintf(app.saveFileID,' t,');
                            end
                            for thisInput = app.inputOptions
                                if app.(sprintf('%sCheckBox',thisInput{1})).Value == 1
                                    fprintf(app.saveFileID,' %s,',thisInput{1});
                                end
                            end
                            fprintf(app.saveFileID,"\nSaplingFrequency: %d\n",app.SamplingFrequencyEditField.Value);
                        end
                        
                        %Start up the recording session
                        app.recordingSession.Rate = app.SamplingFrequencyEditField.Value;
                        app.startTime = datetime('now');
                        start(app.recordingSession,'continuous')
                        

                    catch excpt %if any of that failed
                        app.panic("Recording setup is funky.")
                        app.RecordButton.Value = 0;%turn off recording
                        app.RecordButtonValueChanged(event);%reset
                        
                        % Get and display the full formatted error report
                        errorMessage = getReport(excpt);
                        fprintf(2, '%s\n', errorMessage); 
                        return
                    end


                case 0 %If we are stopping recording, do so and reset
                    stop(app.recordingSession)
                    try
                        if ~isempty(app.saveFileID)
                            fclose(app.saveFileID);
                        end
                        fclose('all');
                    catch excpt
                        % Get and display the full formatted error report
                        errorMessage = getReport(excpt);
                        fprintf(2, '%s\n', errorMessage); 
                        %app.panic("Something went wrong when closing files.");
                    end
                otherwise 
                    app.panic("Recording state is funky.");


            end
        end

        % Button pushed function: SetSaveFileButton
        function SetSaveFileButtonPushed(app, event)
            %Set up dialog to pick a file to save data to
            [file,path] = uiputfile(fullfile(app.baseDir,'*.txt'),'Select Save File Name');
            if file ~= 0
                app.FileNameEditField.Value = [path,file];
                app.baseDir= path;
            end
            figure(app.MainWindow)
        end

        % Value changed function: PlotModeDropDown
        function PlotModeDropDownValueChanged(app, event)
            %toggles options depending on plot mode and calls plot reset
            value = app.PlotModeDropDown.Value;
            app.LegendCheckBox.Enable = "off";
            app.LegendCheckBox.Visible="off";
            switch value
                case "Multiple"
                    visState = "off";
                case "Single"
                    visState = "on";
            end
            app.LegendCheckBox.Enable = visState;
            app.LegendCheckBox.Visible=visState;
            app.clearPlots();
        end

        % Value changed function: ChartHistoryEditField
        function ChartHistoryEditFieldValueChanged(app, event)
            %resets plots
            value = app.ChartHistoryEditField.Value;
            app.clearPlots();
        end

        % Value changed function: RecordingModeDropDown
        function RecordingModeDropDownValueChanged(app, event)
            %toggle options depending on sampling mode
            value = app.RecordingModeDropDown.Value;
            app.NumSamplesEditField.Enable = "off";
            app.NumSamplesEditFieldLabel.Visible="off";
            app.NumSamplesEditField.Visible = "off";
            switch value
                case "continuous"
                    visState = "off";
                case "samples"
                    visState = "on";
            end
            app.NumSamplesEditField.Enable = visState;
            app.NumSamplesEditFieldLabel.Visible=visState;
            app.NumSamplesEditField.Visible = visState;
        end

        % Value changed function: YmodeDropDown
        function YmodeDropDownValueChanged(app, event)
            %toggle options depending on y mode
            value = app.YmodeDropDown.Value;
            app.YminEditField.Enable = "off";
            app.YminEditFieldLabel.Visible="off";
            app.YminEditField.Visible = "off";
            app.YmaxEditField.Enable = "off";
            app.YmaxEditFieldLabel.Visible="off";
            app.YmaxEditField.Visible = "off";
            switch value
                case "Auto"
                    visState = "off";
                case "Fixed"
                    visState = "on";
            end
            app.YminEditField.Enable = visState;
            app.YminEditFieldLabel.Visible=visState;
            app.YminEditField.Visible = visState;
            app.YmaxEditField.Enable = visState;
            app.YmaxEditFieldLabel.Visible=visState;
            app.YmaxEditField.Visible = visState;
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            %resets plots
            value = app.LegendCheckBox.Value;
            app.clearPlots();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            if app.RecordButton.Value == 1
                %if we are recording, stop recording first
                app.RecordButton.Value = 0;
                app.RecordButtonValueChanged(struct())
            end
            
            %start a new instance
            newApp = SignalRecordInator();
            %delete this instance
            figure(newApp.MainWindow)
            uialert(newApp.MainWindow,"The application has been reset and all settings are back to default.","Info Message")
            delete(app.MainWindow)
            
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            try
                %fclose(app.saveFileID);%make sure we close this upon exiting
                fclose("all");
            catch excpt
                % Get and display the full formatted error report
                errorMessage = getReport(excpt);
                fprintf(2, '%s\n', errorMessage); 
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'MATLAB App';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.MainGrid = uigridlayout(app.MainWindow);
            app.OptionTabs = uitabgroup(app.MainGrid);
            app.RecordingTab = uitab(app.OptionTabs);
            app.RecordingTab.Title = 'Recording';
            app.RecordingGrid = uigridlayout(app.RecordingTab);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.RecordingGrid);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.RecordingGrid, 'numeric');
            app.SamplingFrequencyEditField.Limits = [1 5000];
            app.SamplingFrequencyEditField.Value = 500;
            app.InputsPanel = uipanel(app.RecordingGrid);
            app.InputsPanel.Title = 'Inputs';
            app.GridLayout = uigridlayout(app.InputsPanel);
            app.AI0CheckBox = uicheckbox(app.GridLayout);
            app.AI0CheckBox.Text = 'AI0';
            app.AI1CheckBox = uicheckbox(app.GridLayout);
            app.AI1CheckBox.Text = 'AI1';
            app.AI2CheckBox = uicheckbox(app.GridLayout);
            app.AI2CheckBox.Text = 'AI2';
            app.AI3CheckBox = uicheckbox(app.GridLayout);
            app.AI3CheckBox.Text = 'AI3';
            app.RecordingModeDropDownLabel = uilabel(app.RecordingGrid);
            app.RecordingModeDropDownLabel.Text = 'Recording Mode';
            app.RecordingModeDropDown = uidropdown(app.RecordingGrid);
            app.RecordingModeDropDown.Items = {'continuous', 'samples'};
            app.RecordingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingModeDropDownValueChanged, true);
            app.RecordingModeDropDown.Value = 'continuous';
            app.NumSamplesEditFieldLabel = uilabel(app.RecordingGrid);
            app.NumSamplesEditFieldLabel.Enable = 'off';
            app.NumSamplesEditFieldLabel.Visible = 'off';
            app.NumSamplesEditFieldLabel.Text = 'Num Samples';
            app.NumSamplesEditField = uieditfield(app.RecordingGrid, 'numeric');
            app.NumSamplesEditField.Limits = [1 Inf];
            app.NumSamplesEditField.Enable = 'off';
            app.NumSamplesEditField.Visible = 'off';
            app.NumSamplesEditField.Value = 1000;
            app.VisualizingTab = uitab(app.OptionTabs);
            app.VisualizingTab.Title = 'Visualizing';
            app.VisGrid = uigridlayout(app.VisualizingTab);
            app.LineDefsEditFieldLabel = uilabel(app.VisGrid);
            app.LineDefsEditFieldLabel.Text = 'Line Defs';
            app.LineDefsEditField = uieditfield(app.VisGrid, 'text');
            app.LineDefsEditField.Value = 'k-,r-,b-,g-';
            app.PlotModeDropDownLabel = uilabel(app.VisGrid);
            app.PlotModeDropDownLabel.Text = 'Plot Mode';
            app.PlotModeDropDown = uidropdown(app.VisGrid);
            app.PlotModeDropDown.Items = {'Single', 'Multiple'};
            app.PlotModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotModeDropDownValueChanged, true);
            app.PlotModeDropDown.Value = 'Single';
            app.ChartHistoryEditFieldLabel = uilabel(app.VisGrid);
            app.ChartHistoryEditFieldLabel.Text = 'Chart History';
            app.ChartHistoryEditField = uieditfield(app.VisGrid, 'numeric');
            app.ChartHistoryEditField.Limits = [1 Inf];
            app.ChartHistoryEditField.ValueChangedFcn = createCallbackFcn(app, @ChartHistoryEditFieldValueChanged, true);
            app.ChartHistoryEditField.Value = 500;
            app.LegendCheckBox = uicheckbox(app.VisGrid);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.YmaxEditFieldLabel = uilabel(app.VisGrid);
            app.YmaxEditFieldLabel.Enable = 'off';
            app.YmaxEditFieldLabel.Visible = 'off';
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.VisGrid, 'numeric');
            app.YmaxEditField.Enable = 'off';
            app.YmaxEditField.Visible = 'off';
            app.YmaxEditField.Value = 1;
            app.YminEditFieldLabel = uilabel(app.VisGrid);
            app.YminEditFieldLabel.Enable = 'off';
            app.YminEditFieldLabel.Visible = 'off';
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.VisGrid, 'numeric');
            app.YminEditField.Enable = 'off';
            app.YminEditField.Visible = 'off';
            app.YminEditField.Value = -1;
            app.YmodeDropDownLabel = uilabel(app.VisGrid);
            app.YmodeDropDownLabel.Text = 'Y mode';
            app.YmodeDropDown = uidropdown(app.VisGrid);
            app.YmodeDropDown.Items = {'Auto', 'Fixed'};
            app.YmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @YmodeDropDownValueChanged, true);
            app.YmodeDropDown.Value = 'Auto';
            app.SavingTab = uitab(app.OptionTabs);
            app.SavingTab.Title = 'Saving';
            app.GridLayout2 = uigridlayout(app.SavingTab);
            app.FileNameEditFieldLabel = uilabel(app.GridLayout2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout2, 'text');
            app.SetSaveFileButton = uibutton(app.GridLayout2, 'push');
            app.SetSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFileButtonPushed, true);
            app.SetSaveFileButton.Text = 'Set Save File';
            app.SaveDataCheckBox = uicheckbox(app.GridLayout2);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.IncludeTimeColumnCheckBox = uicheckbox(app.GridLayout2);
            app.IncludeTimeColumnCheckBox.Text = 'Include Time Column';
            app.DataSavingMessage = uilabel(app.GridLayout2);
            app.DataSavingMessage.Text = {'While Saving, data will be appended'; 'to the file listed below.'; 'Each new stream will have a header'; 'listing sampling frequency and other'; 'details. Notes will be included in the header'};
            app.NoteEditFieldLabel = uilabel(app.GridLayout2);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.GridLayout2, 'text');
            app.RecordButton = uibutton(app.MainGrid, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.PlotPanel = uipanel(app.MainGrid);
            app.ResetButton = uibutton(app.MainGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalRecordInator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end